classdef GUI16 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        max        matlab.ui.control.NumericEditField
        min        matlab.ui.control.NumericEditField
        EditField  matlab.ui.control.NumericEditField
        Slider     matlab.ui.control.Slider
    end

    
    properties (Access = private)
        Max = 100;
        Min = 1; % Description
        Sli = 49/99*100;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;

            if value<app.min.Value
                app.EditField.Value = app.min.Value;
            elseif value>app.max.Value
                app.EditField.Value = app.max.Value;
            else 
                app.Slider.Value = value;
            
            end

            app.Max  = app.max.Value;
            app.Min  = app.min.Value; % Description
            app.Sli  = app.EditField.Value;

            app.Slider.Value = (app.Sli-app.Min)/(app.Max-app.Min)*100;
        end

        % Value changed function: min
        function minValueChanged(app, event)
            value = app.min.Value;
            if value>app.EditField.Value && value<app.Max
                app.EditField.Value = value;
            elseif value>=app.Max
                app.min.Value = app.Min;
            end

            app.Max  = app.max.Value;
            app.Min  = app.min.Value; % Description
            app.Sli  = app.EditField.Value;

            app.Slider.Value = (app.Sli-app.Min)/(app.Max-app.Min)*100;
        end

        % Value changed function: max
        function maxValueChanged(app, event)
            value = app.max.Value;
            if value<app.EditField.Value && value>app.min.Value
                app.EditField.Value = value;
            elseif value<=app.min.Value
                app.max.Value = app.Max;
            end

            app.Max  = app.max.Value;
            app.Min  = app.min.Value; % Description
            app.Sli  = app.EditField.Value;

            app.Slider.Value = (app.Sli-app.Min)/(app.Max-app.Min)*100;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Value = 49.4949494949495;
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 50;
            app.min = uieditfield(app.UIFigure, 'numeric');
            app.min.ValueChangedFcn = createCallbackFcn(app, @minValueChanged, true);
            app.min.Value = 1;
            app.max = uieditfield(app.UIFigure, 'numeric');
            app.max.ValueChangedFcn = createCallbackFcn(app, @maxValueChanged, true);
            app.max.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI16

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end