classdef GUI19 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        TextArea      matlab.ui.control.TextArea
        PUSH_2Button  matlab.ui.control.Button
        PUSH_1Button  matlab.ui.control.Button
    end

    
    properties (Access = private)
        num double = 0; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: PUSH_1Button
        function PUSH_1ButtonPushed(app, event)
            app.num = app.num+1;
            m = sprintf("Call number: %d \nPUSH_1 called me with : 2 arguments.\n",app.num);
            app.TextArea.Value = m;
        end

        % Button pushed function: PUSH_2Button
        function PUSH_2ButtonPushed(app, event)
            app.num = app.num + 1;
            m = sprintf("Call number: %d \nPUSH_2 called me with : 3 arguments.\n",app.num);
            app.TextArea.Value = m;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PUSH_1Button = uibutton(app.UIFigure, 'push');
            app.PUSH_1Button.ButtonPushedFcn = createCallbackFcn(app, @PUSH_1ButtonPushed, true);
            app.PUSH_1Button.Text = 'PUSH_1';
            app.PUSH_2Button = uibutton(app.UIFigure, 'push');
            app.PUSH_2Button.ButtonPushedFcn = createCallbackFcn(app, @PUSH_2ButtonPushed, true);
            app.PUSH_2Button.Text = 'PUSH_2';
            app.TextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI19

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end