classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        EditField  matlab.ui.control.EditField
        AddButton  matlab.ui.control.Button
        ListBox    matlab.ui.control.ListBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            val = app.EditField.Value;
            num = 0;
            for i = 1:length(app.ListBox.Items)
                if ~strcmp(app.ListBox.Items(i),val)
                    num = num+1;
                end
            end
            if num==length(app.ListBox.Items)
                app.ListBox.Items{end+1} = val;
            end
        end

        % Callback function
        function TextAreaValueChanged(app, event)
       
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            num = 0;
            for i = 1:length(app.ListBox.Items)
                if ~strcmp(app.ListBox.Items(i),value)
                    num = num+1;
                end
            end
            if num==length(app.ListBox.Items)
                app.ListBox.Items{end+1} = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'one', 'two', 'three', 'four'};
            app.ListBox.Value = 'one';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end