classdef GUI5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DeleteMeLabel  matlab.ui.control.Label
        DeleterButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DeleterButton
        function DeleterButtonPushed(app, event)
            
            if strcmp(app.DeleteMeLabel.Text,'')
                app.DeleterButton.Text = 'Nothing to delete!';
                app.DeleterButton.BackgroundColor = 'r';
            else
                m = convertStringsToChars(app.DeleteMeLabel.Text);
                app.DeleteMeLabel.Text = convertCharsToStrings(m(1:end-1));
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeleterButton = uibutton(app.UIFigure, 'push');
            app.DeleterButton.ButtonPushedFcn = createCallbackFcn(app, @DeleterButtonPushed, true);
            app.DeleterButton.Text = 'Deleter';
            app.DeleteMeLabel = uilabel(app.UIFigure);
            app.DeleteMeLabel.Text = 'DeleteMe';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end