classdef GUI6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        NoneselectedButton  matlab.ui.control.Button
        ButtonB             matlab.ui.control.StateButton
        ButtonA             matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function TextAreaValueChanging(app, event)

        end

        % Button pushed function: NoneselectedButton
        function NoneselectedButtonPushed(app, event)
            if (app.ButtonA.Value == 1 && app.ButtonB.Value == 1)
                app.NoneselectedButton.Text = 'Both selected';
            elseif (app.ButtonA.Value == 0 && app.ButtonB.Value == 1)
                app.NoneselectedButton.Text = 'B selected';
            elseif (app.ButtonA.Value == 1 && app.ButtonB.Value == 0)
                app.NoneselectedButton.Text = 'A selected';
            elseif (app.ButtonA.Value == 0 && app.ButtonB.Value == 0)
                app.NoneselectedButton.Text = 'None selected';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonA = uibutton(app.UIFigure, 'state');
            app.ButtonA.Text = 'Button A';
            app.ButtonB = uibutton(app.UIFigure, 'state');
            app.ButtonB.Text = 'Button B';
            app.NoneselectedButton = uibutton(app.UIFigure, 'push');
            app.NoneselectedButton.ButtonPushedFcn = createCallbackFcn(app, @NoneselectedButtonPushed, true);
            app.NoneselectedButton.Text = 'None selected';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end