classdef GUI8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GetCurrentButton  matlab.ui.control.Button
        EditField         matlab.ui.control.NumericEditField
        ButtonGroup       matlab.ui.container.ButtonGroup
        Button4           matlab.ui.control.RadioButton
        Button3           matlab.ui.control.RadioButton
        Button2           matlab.ui.control.RadioButton
        Button1           matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        num = 0; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetCurrentButton
        function GetCurrentButtonButtonPushed(app, event)
           
            if app.Button1.Value == 1
                app.EditField.Value = 1;
                app.num = 1;
            elseif app.Button2.Value == 1
                app.EditField.Value = 2;
                app.num = 2;
            elseif app.Button3.Value == 1
                app.EditField.Value = 3;
                app.num = 3;
            elseif app.Button4.Value == 1
                app.EditField.Value = 4;
                app.num = 4;
            end

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            if value>=4 && value<=1
                app.EditField.Value = app.num;
            else
                app.ButtonGroup.Buttons(value).Value = 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.Button1 = uiradiobutton(app.ButtonGroup);
            app.Button1.Text = 'Button 1';
            app.Button1.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button 2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button 3';
            app.Button4 = uiradiobutton(app.ButtonGroup);
            app.Button4.Text = 'Button 4';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.GetCurrentButton = uibutton(app.UIFigure, 'push');
            app.GetCurrentButton.ButtonPushedFcn = createCallbackFcn(app, @GetCurrentButtonButtonPushed, true);
            app.GetCurrentButton.Text = 'Get Current Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end